classdef model_44_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        EditField               matlab.ui.control.EditField
        EditFieldLabel          matlab.ui.control.Label
        check_clear_volume      matlab.ui.control.CheckBox
        button_start_stop       matlab.ui.control.Button
        menu_units_infuse_rate  matlab.ui.control.DropDown
        etext_infuse_rate_current_value  matlab.ui.control.EditField
        button_set_infuse_rate  matlab.ui.control.Button
        etext_infuse_rate_set   matlab.ui.control.EditField
        text4                   matlab.ui.control.Label
        button_clear_volume     matlab.ui.control.Button
        etext_main_display      matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        function cb_clearVolume(app, obj)
                        obj.instruction = 3;
        end
        
        function cb_setInfuseRate(app, obj)
                        obj.instruction = 4;
        end
        
        function cb_startStop(app, obj)
            
                        if obj.is_running
                            obj.instruction = 2;
                        else
                            obj.instruction = 1;
                        end
        end
        
        function delete(app, obj)
                        if ~isempty(obj.timer)
                            stop(obj.timer);
                            delete(app, obj.timer);
                        end
        end
        
        function h__closeFigure(app, obj)
                delete(app, obj)
                delete(app, gcf);
        end
        
        function updateDisplay(app, obj)
                        %
                        %   This is the main function that runs ...
                        %
                        %
            
                        %'etext_main_display'
                        temp_instruction = obj.instruction;
            
                        try
                            %Now we effectively run
                            switch temp_instruction
                                case 0
                                    vol = obj.p.volume_delivered_ml;
                                    mode = obj.p.current_mode;
            
                                    %TODO: Add on a status query as well besides using last
                                    %populated ...
                                    status = obj.p.pump_status_from_last_query;
            
                                    %TODO: Make this a property of the pump object
                                    temp = status(1) < '3';
            
                                    if isempty(obj.is_running) || temp ~= obj.is_running
                                        if temp
                                            set(obj.h.button_start_stop,'String','Stop')
                                        else
                                            set(obj.h.button_start_stop,'String','Start')
                                        end
                                    end
                                    obj.is_running = temp;
            
                                    str = sprintf('%0.4f ml\nMode: %s\n%s',vol,mode,status);
                                    set(obj.h.etext_main_display,'String',str);
                                case 1
                                    if get(obj.h.check_clear_volume,'Value')
                                       obj.p.clearDeliveredVolume();
                                    end
                                    obj.p.start();
                                case 2
                                    obj.p.stop();
                                case 3
                                    obj.p.clearDeliveredVolume();
                                case 4
                                    all_units = get(obj.h.menu_units_infuse_rate,'String');
                                    cur_value = get(obj.h.menu_units_infuse_rate,'Value');
                                    %TODO: Fix this so that units is a local property
                                    %to index into
                                    units = strtrim(all_units{cur_value});
                                    new_rate = str2double(get(obj.h.etext_infuse_rate_set,'String'));
                                    obj.p.setInfuseRate(new_rate,units);
                                    %TODO: We could copy here, rather than doing
                                    %another call
                                 	temp = obj.p.infuse_rate;
                                    set(obj.h.etext_infuse_rate_current_value,'String',sprintf('%0.4f %s',temp{1},temp{2}));
                            end
            
                        catch ME
                            if isvalid(obj)
                                fprintf(2,'ERROR DETECTED: Timer shutting down\n')
                                disp(ME)
                                stop(obj.timer);
                                delete(app, obj.timer);
                                obj.timer = [];
                            end
                        end
            
                        if isvalid(obj)
                            %I'm not sure how to handle this ....
                            if temp_instruction == obj.instruction
                                %Hopefully this allows remote updates ...
                                obj.instruction = 0;
                            end
                        end
        end
        
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'model_44_gui';
            app.figure1.Tag = 'figure1';
            app.etext_main_display = uitextarea(app.figure1);
            app.etext_main_display.Tag = 'etext_main_display';
            app.etext_main_display.Value = {'0.11778 ml'};
            app.button_clear_volume = uibutton(app.figure1, 'push');
            app.button_clear_volume.Tag = 'button_clear_volume';
            app.button_clear_volume.Text = 'Clear Volume';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'Infuse'; 'Rate'};
            app.etext_infuse_rate_set = uieditfield(app.figure1, 'text');
            app.etext_infuse_rate_set.Tag = 'etext_infuse_rate_set';
            app.etext_infuse_rate_set.Value = '1.1234';
            app.button_set_infuse_rate = uibutton(app.figure1, 'push');
            app.button_set_infuse_rate.Tag = 'button_set_infuse_rate';
            app.button_set_infuse_rate.Text = 'Set =>';
            app.etext_infuse_rate_current_value = uieditfield(app.figure1, 'text');
            app.etext_infuse_rate_current_value.Tag = 'etext_infuse_rate_current_value';
            app.etext_infuse_rate_current_value.Value = '1.1234 ml/mn';
            app.menu_units_infuse_rate = uidropdown(app.figure1);
            app.menu_units_infuse_rate.Items = {'  ul/hr', '  ul/min', '  ml/hr', '  ml/min'};
            app.menu_units_infuse_rate.Tag = 'menu_units_infuse_rate';
            app.menu_units_infuse_rate.Value = '  ul/hr';
            app.button_start_stop = uibutton(app.figure1, 'push');
            app.button_start_stop.Tag = 'button_start_stop';
            app.button_start_stop.Text = 'Start';
            app.check_clear_volume = uicheckbox(app.figure1);
            app.check_clear_volume.Tag = 'check_clear_volume';
            app.check_clear_volume.Text = 'Clear on Start';
            app.EditFieldLabel = uilabel(app.figure1);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.figure1, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_44_gui_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end